classdef HPF_BassRemoverRevisiFinal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Panel_2                         matlab.ui.container.Panel
        ModeButtonGroup                 matlab.ui.container.ButtonGroup
        FilterButton                    matlab.ui.control.RadioButton
        AudioFilterButton               matlab.ui.control.RadioButton
        TimeRangeButtonGroup            matlab.ui.container.ButtonGroup
        StopEditField                   matlab.ui.control.NumericEditField
        StopEditFieldLabel              matlab.ui.control.Label
        StartEditField                  matlab.ui.control.NumericEditField
        StartEditFieldLabel             matlab.ui.control.Label
        SaveFileButton                  matlab.ui.control.Button
        FilteredButton                  matlab.ui.control.Button
        OriginalButton                  matlab.ui.control.Button
        InputPanel                      matlab.ui.container.Panel
        SampleRateHzEditField           matlab.ui.control.NumericEditField
        SampleRateHzEditFieldLabel      matlab.ui.control.Label
        OrdeEditFieldLabel              matlab.ui.control.Label
        OrdeEditField                   matlab.ui.control.NumericEditField
        CutOffFrequencyHzKnobLabel      matlab.ui.control.Label
        CutOffFrequencyHzKnob           matlab.ui.control.Knob
        InputAudioFilePanel             matlab.ui.container.Panel
        namafileLabel                   matlab.ui.control.Label
        InsertButton                    matlab.ui.control.Button
        frequencyshow                   matlab.ui.control.NumericEditField
        CommandPanelButtonGroup         matlab.ui.container.ButtonGroup
        FrequencyPlotButton             matlab.ui.control.RadioButton
        TimePlotButton                  matlab.ui.control.RadioButton
        FilterConfigurationButton       matlab.ui.control.RadioButton
        WindowingMethodButtonGroup      matlab.ui.container.ButtonGroup
        BetaEditField                   matlab.ui.control.NumericEditField
        BetaEditFieldLabel              matlab.ui.control.Label
        BlackmanButton                  matlab.ui.control.RadioButton
        KaiserButton                    matlab.ui.control.RadioButton
        HanningButton                   matlab.ui.control.RadioButton
        HammingButton                   matlab.ui.control.RadioButton
        dotButton                       matlab.ui.control.RadioButton
        InstructionsButton              matlab.ui.control.Button
        RUNButton                       matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        InstructionsPanel               matlab.ui.container.Panel
        OKButton                        matlab.ui.control.Button
        CaraMenggunakanAplikasBassRemoverLabel  matlab.ui.control.Label
        OutputPanelFrequencyPlot        matlab.ui.container.Panel
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        OutputPanelTimePlot             matlab.ui.container.Panel
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        OutputPanelFilterConfiguration  matlab.ui.container.Panel
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        MRafliAFauzaanTVirginiaValensieneEMariscaAuliaLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        HighPassFIRFilterAplicationLabel  matlab.ui.control.Label
        BassRemoverLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        value ; % Description
        fcutoff = 0.5;
        audioData ;
        audioData1;
        sampleRate = 48000;
        result ;
        N=250;
        HPF;
        start = 1;
        stop = 10;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.BetaEditField.Visible = "off";
            app.BetaEditFieldLabel.Visible = "off";
            app.InstructionsPanel.Visible ="off";
            app.namafileLabel.Visible = "off";
            app.OutputPanelTimePlot.Visible = "off";
            app.OutputPanelFrequencyPlot.Visible = "off";
            app.dotButton.Value = true;
            app.HammingButton.Value = false;
            app.HanningButton.Value = false;
            app.BlackmanButton.Value = false;
            app.KaiserButton.Value = false;
            app.SampleRateHzEditField.Visible = "off";
            app.SampleRateHzEditFieldLabel.Visible = "off";
            
            
        end

        % Button pushed function: InstructionsButton
        function InstructionsButtonPushed(app, event)
            app.InstructionsPanel.Visible ="on";
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.InstructionsPanel.Visible ="off";
        end

        % Selection changed function: ModeButtonGroup
        function ModeButtonGroupSelectionChanged(app, event)
            % Reset the values back to default
            app.StartEditField.Value = 0;
            app.StopEditField.Value = 0;
            app.CutOffFrequencyHzKnob.Value = 0;
            app.frequencyshow.Value = 0;
            app.OrdeEditField.Value = 0;
            app.SampleRateHzEditField.Value = 0;
            app.BetaEditField.Value = 0;
            app.InsertButton.Text = 'Insert...';
            app.namafileLabel.Visible = "off";
            
            %Reset the Output Panel
            plot(app.UIAxes, 0, 0);
            plot(app.UIAxes_2, 0, 0);
            plot(app.UIAxes_3, 0, 0);
            plot(app.UIAxes_4, 0, 0);
            plot(app.UIAxes_5, 0, 0);
            plot(app.UIAxes_6, 0, 0);
            
            % Change Mode
            selectedButton = app.ModeButtonGroup.SelectedObject;
            if selectedButton == app.FilterButton
                app.CaraMenggunakanAplikasBassRemoverLabel.Text = {'1. Masukkan Sample Rate yang diinginkan'; '2. Masukkan nilai Orde yang diinginkan'; '3. Atur frekuensi cut-off yang diinginkan dengan mengatur knob pada Input Pannel'; '4. Pilihlah metode windowing pada Windowing Method'; '5. Masukkan nilai Beta apabila menggunakan windowing Kaiser'; '6. Apabila anda mengubah nilai input termasuk memasukkan nilai Beta, harap tekan metode lain lalu tekan ulang metode yang dipilih untuk memunculkan grafik yang baru'; '7. Grafik akan langsung muncul pada output panel'; '8. Jangan lupa menekan Filter Configuration pada Command Panel apabila belum terpilih'};
                app.InsertButton.Visible = "off";
                app.SampleRateHzEditField.Visible = "on";
                app.SampleRateHzEditFieldLabel.Visible = "on";
                app.InputAudioFilePanel.Visible = "off";
                app.TimeRangeButtonGroup.Visible = "off";
                app.TimePlotButton.Visible = "off";
                app.FrequencyPlotButton.Visible = "off";
                app.RUNButton.Visible = "off";
                app.SaveFileButton.Visible = "off";
                app.FilteredButton.Visible = "off";
                app.OriginalButton.Visible = "off";
                app.WindowingMethodButtonGroup.Position = [16 153 281 125];
                app.CommandPanelButtonGroup.Position = [15 12 282 133];
            elseif selectedButton == app.AudioFilterButton
                app.CaraMenggunakanAplikasBassRemoverLabel.Text = {'1. Masukkan file audio (.wav) yang diinginkan dengan tombol Insert'; '2. Masukkan nilai Orde yang diinginkan'; '3. Atur frekuensi cut-off yang diinginkan dengan mengatur knob pada Input Pannel                                                          4. Masukkan nilai Start dan Stop untuk menentukan range waktu yang diinginkan.                                                   '; '5. Pilihlah metode windowing pada Windowing Method'; '6. Masukkan nilai Beta apabila menggunakan windowing Kaiser'; '6. Tekan tombol RUN untuk menjalankan filter'; '7. Pilihlah pada Command Pannel untuk melihat hasil grafik yang berbeda sesuai dengan yang diinginkan'; '8. Tekan tombol Original untuk memutar audio sebelum difilter dan tekan tombol Filtered untuk memutar audio setelah difilter serta save untuk menyimpan'};
                app.SampleRateHzEditField.Visible = "off";
                app.SampleRateHzEditFieldLabel.Visible = "off";
                app.InsertButton.Visible = "on";
                app.InputAudioFilePanel.Visible = "on";
                app.TimeRangeButtonGroup.Visible = "on";
                app.TimePlotButton.Visible = "on";
                app.FrequencyPlotButton.Visible = "on";
                app.RUNButton.Visible = "on";
                app.SaveFileButton.Visible = "on";
                app.FilteredButton.Visible = "on";
                app.OriginalButton.Visible = "on";
                app.WindowingMethodButtonGroup.Position = [130 153 167 125];
                app.CommandPanelButtonGroup.Position = [15 52 149 93];
            end            
        end

        % Button pushed function: InsertButton
        function InsertButtonPushed(app, event)
           % Open a dialog to select a WAV file
            [fileName, filePath] = uigetfile('*.wav', 'Select WAV file');
            if isequal(fileName, 0) || isequal(filePath, 0)
                % User canceled the file selection
                return;
            end

            % Concatenate the file path and name
            wavFilePath = fullfile(filePath, fileName);
        
            % Load the WAV file using the audioread function
            [app.audioData1, app.sampleRate] = audioread(wavFilePath);
            

            %display audio file name
            app.namafileLabel.Visible="on";
            app.namafileLabel.Text=fileName;

        end

        % Value changed function: StartEditField
        function StartEditFieldValueChanged(app, event)
            app.start = app.StartEditField.Value;
        end

        % Value changed function: StopEditField
        function StopEditFieldValueChanged(app, event)
            app.stop = app.StopEditField.Value;
            
        end

        % Value changed function: CutOffFrequencyHzKnob
        function CutOffFrequencyHzKnobValueChanged(app, event)
            app.value = app.CutOffFrequencyHzKnob.Value;
            app.frequencyshow.Value = app.value;
        end

        % Value changing function: CutOffFrequencyHzKnob
        function CutOffFrequencyHzKnobValueChanging(app, event)
            changingValue = event.Value;
            app.frequencyshow.Value = changingValue;
        end

        % Value changed function: frequencyshow
        function frequencyshowValueChanged(app, event)
            app.value = app.frequencyshow.Value;
            app.CutOffFrequencyHzKnob.Value = app.value;
        end

        % Value changed function: OrdeEditField
        function OrdeEditFieldValueChanged(app, event)
            app.N = app.OrdeEditField.Value;
            
        end

        % Selection changed function: WindowingMethodButtonGroup
        function WindowingMethodButtonGroupSelectionChanged(app, event)
            selectedButton = app.WindowingMethodButtonGroup.SelectedObject;
            app.BetaEditField.Visible = "off";
            app.BetaEditFieldLabel.Visible = "off";
            app.fcutoff = app.value/(app.sampleRate/2);
            
            %select windowing method
            if selectedButton== app.dotButton
            elseif selectedButton == app.HammingButton 
                app.HPF = fir1(app.N, app.fcutoff, 'high');
            elseif selectedButton == app.HanningButton
                app.HPF = fir1(app.N, app.fcutoff, 'high', hanning(app.N + 1));
            elseif selectedButton == app.BlackmanButton
                app.HPF = fir1(app.N, app.fcutoff, 'high', blackman(app.N + 1));
            elseif selectedButton == app.KaiserButton
                app.BetaEditField.Visible = "on";
                app.BetaEditFieldLabel.Visible = "on";
                beta = app.BetaEditField.Value; % Adjust the beta value to control sidelobe levels and transition width
                app.HPF = fir1(app.N, app.fcutoff, 'high', kaiser(app.N + 1,beta));
            end
             [h, o]=freqz(app.HPF, 1, 256, app.sampleRate);
                m = 20*log10(abs(h));
                phase = unwrap(angle(h));
                plot(app.UIAxes, o, m);
                plot(app.UIAxes_2, o, phase);
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
                app.audioData = app.audioData1(app.sampleRate*app.start : app.sampleRate*app.stop);
            app.result = filter(app.HPF,1,app.audioData);
           
                L = length(app.audioData);
                %t = L/app.sampleRate;
                tr = app.stop - app.start;
                n = app.start:tr/(L-1):app.stop;
                %a = 1:length(app.result);
             
                plot(app.UIAxes_3, n, app.audioData);
                plot(app.UIAxes_4, n, app.result);

                L1 = length(app.audioData);
                Y = fft(app.audioData);
                P2 = abs(Y/L1);
                P1 = P2(1:L1/2+1);
                P1(2:end-1) = 2*P1(2:end-1);
                f1 = app.sampleRate*(0:(L1/2))/L1;


                % Plot the FFT on the Axes component
                plot(app.UIAxes_5, f1, P1);

                L = length(app.audioData);
                X = fft(app.result);
                P2x = abs(X/L);
                P1x = P2x(1:L/2+1);
                P1x(2:end-1) = 2*P1x(2:end-1);
                f2 = app.sampleRate*(0:(L/2))/L;

                plot(app.UIAxes_6, f2, P1x);
        end

        % Selection changed function: CommandPanelButtonGroup
        function CommandPanelButtonGroupSelectionChanged(app, event)
            selectedButton = app.CommandPanelButtonGroup.SelectedObject;
            if selectedButton == app.FilterConfigurationButton
                app.OutputPanelTimePlot.Visible = "off";
                app.OutputPanelFrequencyPlot.Visible = "off";
            elseif selectedButton == app.TimePlotButton
                app.OutputPanelTimePlot.Visible = "on";
            elseif selectedButton == app.FrequencyPlotButton
                app.OutputPanelFrequencyPlot.Visible = "on";
            end
        end

        % Button pushed function: OriginalButton
        function OriginalButtonPushed(app, event)
            sound (app.audioData, app.sampleRate);
        end

        % Button pushed function: FilteredButton
        function FilteredButtonPushed(app, event)
            sound (app.result, app.sampleRate);
        end

        % Button pushed function: SaveFileButton
        function SaveFileButtonPushed(app, event)
            [filename1, pathname1] = uiputfile('*.wav', 'Save File');
            if isnumeric(filename1); return; end   %user cancel
            fullname = fullfile(pathname1, filename1);
            audiowrite(fullname, p, Fs);
        end

        % Callback function
        function SampleRateHzEditFieldValueChanged(app, event)
            app.SampleRate = app.SampleRateHzEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.BassRemoverLabel = uilabel(app.Panel);
            app.BassRemoverLabel.Text = 'Bass Remover';
            app.HighPassFIRFilterAplicationLabel = uilabel(app.Panel);
            app.HighPassFIRFilterAplicationLabel.Text = 'High Pass FIR Filter Aplication';
            app.Label = uilabel(app.Panel);
            app.Label.Text = '|';
            app.MRafliAFauzaanTVirginiaValensieneEMariscaAuliaLabel = uilabel(app.Panel);
            app.MRafliAFauzaanTVirginiaValensieneEMariscaAuliaLabel.Text = {'M Rafli A'; 'Fauzaan T'; 'Virginia Valensiene E'; 'Marisca Aulia'};
            app.OutputPanelFilterConfiguration = uipanel(app.Panel);
            app.OutputPanelFilterConfiguration.Title = 'Output Panel - Filter Configuration';
            app.UIAxes = uiaxes(app.OutputPanelFilterConfiguration);
            xlabel(app.UIAxes, 'Frequenzy (Hz)')
            ylabel(app.UIAxes, 'Magnitude (dB)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.OutputPanelFilterConfiguration);
            xlabel(app.UIAxes_2, 'Frequency (Hz)')
            ylabel(app.UIAxes_2, 'Phase (Degree)')
            zlabel(app.UIAxes_2, 'Z')
            app.OutputPanelTimePlot = uipanel(app.Panel);
            app.OutputPanelTimePlot.Title = 'Output Panel - Time Plot';
            app.UIAxes_3 = uiaxes(app.OutputPanelTimePlot);
            title(app.UIAxes_3, 'Raw Audio')
            xlabel(app.UIAxes_3, 'Time (s)')
            ylabel(app.UIAxes_3, 'Amplitude (mV)')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.OutputPanelTimePlot);
            title(app.UIAxes_4, 'Filtered Audio')
            xlabel(app.UIAxes_4, 'Time (s)')
            ylabel(app.UIAxes_4, 'Amplitude (mV)')
            zlabel(app.UIAxes_4, 'Z')
            app.OutputPanelFrequencyPlot = uipanel(app.Panel);
            app.OutputPanelFrequencyPlot.Title = 'Output Panel - Frequency Plot';
            app.UIAxes_5 = uiaxes(app.OutputPanelFrequencyPlot);
            title(app.UIAxes_5, 'Raw Audio')
            xlabel(app.UIAxes_5, 'Frequency (Hz)')
            ylabel(app.UIAxes_5, 'Amplitude (mV)')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.OutputPanelFrequencyPlot);
            title(app.UIAxes_6, 'Filtered Audio')
            xlabel(app.UIAxes_6, 'Frequency (Hz)')
            ylabel(app.UIAxes_6, 'Amplitude (mV)')
            zlabel(app.UIAxes_6, 'Z')
            app.InstructionsPanel = uipanel(app.Panel);
            app.InstructionsPanel.Title = 'Instructions (Lakukan berurutan agar mendapat hasil yang diinginkan)';
            app.CaraMenggunakanAplikasBassRemoverLabel = uilabel(app.InstructionsPanel);
            app.CaraMenggunakanAplikasBassRemoverLabel.Text = {'1. Masukkan file audio (.wav) yang diinginkan dengan tombol Insert'; '2. Masukkan nilai Orde yang diinginkan'; '3. Atur frekuensi cut-off yang diinginkan dengan mengatur knob pada Input Pannel                                                          4. Masukkan nilai Start dan Stop untuk menentukan range waktu yang diinginkan.                                                   '; '5. Pilihlah metode windowing pada Windowing Method'; '6. Masukkan nilai Beta apabila menggunakan windowing Kaiser'; '7. Tekan tombol RUN untuk menjalankan filter'; '8. Pilihlah pada Command Pannel untuk melihat hasil grafik yang berbeda sesuai dengan yang diinginkan'; '9. Tekan tombol Original untuk memutar audio sebelum difilter dan tekan tombol Filtered untuk memutar audio setelah difilter serta save untuk menyimpan'};
            app.OKButton = uibutton(app.InstructionsPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.Panel_2 = uipanel(app.UIFigure);
            app.RUNButton = uibutton(app.Panel_2, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.InstructionsButton = uibutton(app.Panel_2, 'push');
            app.InstructionsButton.ButtonPushedFcn = createCallbackFcn(app, @InstructionsButtonPushed, true);
            app.InstructionsButton.Text = 'Instructions';
            app.WindowingMethodButtonGroup = uibuttongroup(app.Panel_2);
            app.WindowingMethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @WindowingMethodButtonGroupSelectionChanged, true);
            app.WindowingMethodButtonGroup.Title = 'Windowing Method';
            app.dotButton = uiradiobutton(app.WindowingMethodButtonGroup);
            app.dotButton.Visible = 'off';
            app.dotButton.Text = 'dot';
            app.HammingButton = uiradiobutton(app.WindowingMethodButtonGroup);
            app.HammingButton.Text = 'Hamming';
            app.HammingButton.Value = true;
            app.HanningButton = uiradiobutton(app.WindowingMethodButtonGroup);
            app.HanningButton.Text = 'Hanning';
            app.KaiserButton = uiradiobutton(app.WindowingMethodButtonGroup);
            app.KaiserButton.Text = 'Kaiser';
            app.BlackmanButton = uiradiobutton(app.WindowingMethodButtonGroup);
            app.BlackmanButton.Text = 'Blackman';
            app.BetaEditFieldLabel = uilabel(app.WindowingMethodButtonGroup);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.WindowingMethodButtonGroup, 'numeric');
            app.CommandPanelButtonGroup = uibuttongroup(app.Panel_2);
            app.CommandPanelButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CommandPanelButtonGroupSelectionChanged, true);
            app.CommandPanelButtonGroup.Title = 'Command Panel';
            app.FilterConfigurationButton = uiradiobutton(app.CommandPanelButtonGroup);
            app.FilterConfigurationButton.Text = 'Filter Configuration';
            app.FilterConfigurationButton.Value = true;
            app.TimePlotButton = uiradiobutton(app.CommandPanelButtonGroup);
            app.TimePlotButton.Text = 'Time Plot';
            app.FrequencyPlotButton = uiradiobutton(app.CommandPanelButtonGroup);
            app.FrequencyPlotButton.Text = 'Frequency Plot';
            app.InputPanel = uipanel(app.Panel_2);
            app.InputPanel.Title = 'Input Panel';
            app.frequencyshow = uieditfield(app.InputPanel, 'numeric');
            app.frequencyshow.ValueChangedFcn = createCallbackFcn(app, @frequencyshowValueChanged, true);
            app.InputAudioFilePanel = uipanel(app.InputPanel);
            app.InputAudioFilePanel.Title = 'Input Audio File';
            app.InsertButton = uibutton(app.InputAudioFilePanel, 'push');
            app.InsertButton.ButtonPushedFcn = createCallbackFcn(app, @InsertButtonPushed, true);
            app.InsertButton.Text = 'Insert...';
            app.namafileLabel = uilabel(app.InputAudioFilePanel);
            app.namafileLabel.Text = 'nama file';
            app.CutOffFrequencyHzKnob = uiknob(app.InputPanel, 'continuous');
            app.CutOffFrequencyHzKnob.Limits = [0 2500];
            app.CutOffFrequencyHzKnob.ValueChangedFcn = createCallbackFcn(app, @CutOffFrequencyHzKnobValueChanged, true);
            app.CutOffFrequencyHzKnob.ValueChangingFcn = createCallbackFcn(app, @CutOffFrequencyHzKnobValueChanging, true);
            app.CutOffFrequencyHzKnobLabel = uilabel(app.InputPanel);
            app.CutOffFrequencyHzKnobLabel.Text = 'Cut Off Frequency (Hz)';
            app.OrdeEditField = uieditfield(app.InputPanel, 'numeric');
            app.OrdeEditField.ValueChangedFcn = createCallbackFcn(app, @OrdeEditFieldValueChanged, true);
            app.OrdeEditFieldLabel = uilabel(app.InputPanel);
            app.OrdeEditFieldLabel.Text = 'Orde';
            app.SampleRateHzEditFieldLabel = uilabel(app.InputPanel);
            app.SampleRateHzEditFieldLabel.Visible = 'off';
            app.SampleRateHzEditFieldLabel.Text = 'Sample Rate (Hz)';
            app.SampleRateHzEditField = uieditfield(app.InputPanel, 'numeric');
            app.SampleRateHzEditField.Visible = 'off';
            app.OriginalButton = uibutton(app.Panel_2, 'push');
            app.OriginalButton.ButtonPushedFcn = createCallbackFcn(app, @OriginalButtonPushed, true);
            app.OriginalButton.Text = '▶ Original';
            app.FilteredButton = uibutton(app.Panel_2, 'push');
            app.FilteredButton.ButtonPushedFcn = createCallbackFcn(app, @FilteredButtonPushed, true);
            app.FilteredButton.Text = '▶ Filtered';
            app.SaveFileButton = uibutton(app.Panel_2, 'push');
            app.SaveFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFileButtonPushed, true);
            app.SaveFileButton.Text = 'Save File';
            app.TimeRangeButtonGroup = uibuttongroup(app.Panel_2);
            app.TimeRangeButtonGroup.Title = 'Time Range';
            app.StartEditFieldLabel = uilabel(app.TimeRangeButtonGroup);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.TimeRangeButtonGroup, 'numeric');
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @StartEditFieldValueChanged, true);
            app.StopEditFieldLabel = uilabel(app.TimeRangeButtonGroup);
            app.StopEditFieldLabel.Text = 'Stop';
            app.StopEditField = uieditfield(app.TimeRangeButtonGroup, 'numeric');
            app.StopEditField.ValueChangedFcn = createCallbackFcn(app, @StopEditFieldValueChanged, true);
            app.ModeButtonGroup = uibuttongroup(app.Panel_2);
            app.ModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ModeButtonGroupSelectionChanged, true);
            app.ModeButtonGroup.Title = 'Mode';
            app.AudioFilterButton = uiradiobutton(app.ModeButtonGroup);
            app.AudioFilterButton.Text = 'Audio Filter';
            app.AudioFilterButton.Value = true;
            app.FilterButton = uiradiobutton(app.ModeButtonGroup);
            app.FilterButton.Text = 'Filter ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HPF_BassRemoverRevisiFinal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end